classdef ExportDataApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExportData          matlab.ui.Figure
        FileMenu            matlab.ui.container.Menu
        EditMenu            matlab.ui.container.Menu
        ViewMenu            matlab.ui.container.Menu
        DataBrowserPanel    matlab.ui.container.Panel
        CurrentProjectTree  matlab.ui.container.Tree
        FolderNode          matlab.ui.container.TreeNode
        Node_3              matlab.ui.container.TreeNode
        Node_4              matlab.ui.container.TreeNode
        Node                matlab.ui.container.TreeNode
        Node_5              matlab.ui.container.TreeNode
        Node_2              matlab.ui.container.TreeNode
    end

    
    properties (Access = public)
        CallingAppObj; % Description
        MainAppObj;
    end
    
    methods (Access = public)
        
        function addFolderToTree(app,folderNode,folderToAdd)
            listOfFiles = dir(folderToAdd);
            folderSize = size(listOfFiles,1);
            
            for idx = 1:folderSize
                drawnow();
                
                if (~startsWith(listOfFiles(idx).name,"."))
                    if (listOfFiles(idx).isdir)
                        newNode = uitreenode(folderNode,'Text',listOfFiles(idx).name);
                        addFolderToTree(app,newNode,[listOfFiles(idx).folder '/' listOfFiles(idx).name])
                    else
                        uitreenode(folderNode,'Text',listOfFiles(idx).name);
                    end
                end
            end
            
        end
        
        function updateFileTree(app,rootFolder)
            delete(app.FolderNode.Children)
            app.FolderNode.Text = rootFolder;
            
            f = uifigure('Name','Directory Indexing');
            fileLoadingProgressBar = uiprogressdlg(f,'Title','Opening Folder and Subfolders. . .','Indeterminate','on');
            pause(.5)
            app.MainAppObj.setAppOnTop(true,f.Name);
            addFolderToTree(app,app.FolderNode,rootFolder);
            
            close(fileLoadingProgressBar);
            delete(fileLoadingProgressBar);
            clear fileLoadingProgressBar;
            close(f)
            delete(f)
            clear f;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, mainApp)
            
            app.CallingAppObj = callingApp;
            app.MainAppObj = mainApp;
            updateFileTree(app,app.MainAppObj.ProjectDirectoryObj);
            pause(0.5);
            
            app.MainAppObj.setAppOnTop(true,app.ExportData.Name);
            
            app.MainAppObj.DataToWriteObj = load([cd '/MeasuredData/testData.mat']).dataToWrite; %Remove once you have actual data
           
            for AZPos = 2:length(app.MainAppObj.DataToWriteObj)
                fprintf("%.2f\n",app.MainAppObj.DataToWriteObj{AZPos}(1,2,1));
            end
        end

        % Close request function: ExportData
        function ExportDataCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExportData = uifigure('Visible', 'off');
            app.ExportData.Name = 'Select Data to Export';
            app.ExportData.CloseRequestFcn = createCallbackFcn(app, @ExportDataCloseRequest, true);
            app.FileMenu = uimenu(app.ExportData);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.ExportData);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.ExportData);
            app.ViewMenu.Text = 'View';
            app.DataBrowserPanel = uipanel(app.ExportData);
            app.DataBrowserPanel.Title = 'Data Browser';
            app.CurrentProjectTree = uitree(app.DataBrowserPanel);
            app.FolderNode = uitreenode(app.CurrentProjectTree);
            app.FolderNode.Text = '-90';
            app.Node_3 = uitreenode(app.FolderNode);
            app.Node_3.Text = '0';
            app.Node = uitreenode(app.CurrentProjectTree);
            app.Node.Text = '-89';
            app.Node_4 = uitreenode(app.Node);
            app.Node_4.Text = 'Node';
            app.Node_2 = uitreenode(app.CurrentProjectTree);
            app.Node_2.Text = '-88';
            app.Node_5 = uitreenode(app.Node_2);
            app.Node_5.Text = 'Node';
            % Show the figure after all components are created
            app.ExportData.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExportDataApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExportData)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExportData)
        end
    end
end