classdef TakeMeasurementApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TakeMeasurement                matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        DataPlotsPanel                 matlab.ui.container.Panel
        SetParamsPanel                 matlab.ui.container.Panel
        VNAPanel                       matlab.ui.container.Panel
        FrequencyEditFieldLabel        matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.NumericEditField
        StartEditFieldLabel            matlab.ui.control.Label
        StartEditField                 matlab.ui.control.NumericEditField
        StopEditFieldLabel             matlab.ui.control.Label
        StopEditField                  matlab.ui.control.NumericEditField
        SpanFrequencyCheckBox          matlab.ui.control.CheckBox
        FreqUnitsDropDown              matlab.ui.control.DropDown
        PositionControllerPanel        matlab.ui.container.Panel
        IncrementSizeEditFieldLabel    matlab.ui.control.Label
        IncrementSizeEditField         matlab.ui.control.NumericEditField
        StartPositionEditFieldLabel    matlab.ui.control.Label
        StartPositionEditField         matlab.ui.control.NumericEditField
        EndPositionEditFieldLabel      matlab.ui.control.Label
        EndPositionEditField           matlab.ui.control.NumericEditField
        PositionControllerLimitsLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingAppObj; % Description
        MainAppObj;
        
        ProjectDirectoryObj;
        ProjectFileObj;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, mainApp, incrementSize, startPos, endPos, freqValue, freqUnits, startFreq, stopFreq, spanFreq)
            app.CallingAppObj = callingApp;
            app.MainAppObj = mainApp;
            
            app.IncrementSizeEditField.Value = incrementSize;
            app.StartPositionEditField.Value = startPos;
            app.EndPositionEditField.Value = endPos;
            
            if (spanFreq)
                app.StartEditField.Enable = 'on';
                app.StopEditField.Enable = 'on';
                app.StartEditFieldLabel.Enable = 'on';
                app.StopEditFieldLabel.Enable = 'on';
                app.FrequencyEditField.Enable = 'off';
                app.FrequencyEditFieldLabel.Enable = 'off';
                app.FreqUnitsDropDown.Enable = 'off';
            end
            
            app.FrequencyEditField.Value = freqValue;
            app.FreqUnitsDropDown.Value = freqUnits;
            app.StartEditField.Value = startFreq;
            app.StopEditField.Value = stopFreq;
            app.SpanFrequencyCheckBox.Value = spanFreq;
            
            pause(0.5);
            
            app.MainAppObj.setAppOnTop(true,app.TakeMeasurement.Name);
            app.ProjectDirectoryObj = app.MainAppObj.ProjectDirectoryObj;
            app.ProjectFileObj = app.MainAppObj.ProjectFileObj;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TakeMeasurement = uifigure('Visible', 'off');
            app.TakeMeasurement.Name = 'UI Figure';
            app.FileMenu = uimenu(app.TakeMeasurement);
            app.FileMenu.Text = 'File';
            app.Menu = uimenu(app.FileMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.FileMenu);
            app.Menu2.Text = 'Menu2';
            app.EditMenu = uimenu(app.TakeMeasurement);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.TakeMeasurement);
            app.ViewMenu.Text = 'View';
            app.DataPlotsPanel = uipanel(app.TakeMeasurement);
            app.DataPlotsPanel.Title = 'Data & Plots';
            app.SetParamsPanel = uipanel(app.TakeMeasurement);
            app.SetParamsPanel.Title = 'Parameters For Measurement';
            app.VNAPanel = uipanel(app.SetParamsPanel);
            app.VNAPanel.Title = 'VNA';
            app.FrequencyEditFieldLabel = uilabel(app.VNAPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.VNAPanel, 'numeric');
            app.FrequencyEditField.Editable = 'off';
            app.StartEditFieldLabel = uilabel(app.VNAPanel);
            app.StartEditFieldLabel.Enable = 'off';
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.VNAPanel, 'numeric');
            app.StartEditField.Editable = 'off';
            app.StartEditField.Enable = 'off';
            app.StopEditFieldLabel = uilabel(app.VNAPanel);
            app.StopEditFieldLabel.Enable = 'off';
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.VNAPanel, 'numeric');
            app.StopEditField.Editable = 'off';
            app.StopEditField.Enable = 'off';
            app.SpanFrequencyCheckBox = uicheckbox(app.VNAPanel);
            app.SpanFrequencyCheckBox.Text = ' Span Frequency';
            app.FreqUnitsDropDown = uidropdown(app.VNAPanel);
            app.FreqUnitsDropDown.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.FreqUnitsDropDown.Value = 'GHz';
            app.PositionControllerPanel = uipanel(app.SetParamsPanel);
            app.PositionControllerPanel.Title = 'Position Controller';
            app.IncrementSizeEditFieldLabel = uilabel(app.PositionControllerPanel);
            app.IncrementSizeEditFieldLabel.Text = 'Increment Size';
            app.IncrementSizeEditField = uieditfield(app.PositionControllerPanel, 'numeric');
            app.IncrementSizeEditField.Editable = 'off';
            app.StartPositionEditFieldLabel = uilabel(app.PositionControllerPanel);
            app.StartPositionEditFieldLabel.Text = 'Start Position';
            app.StartPositionEditField = uieditfield(app.PositionControllerPanel, 'numeric');
            app.StartPositionEditField.Editable = 'off';
            app.StartPositionEditField.Value = -90;
            app.EndPositionEditFieldLabel = uilabel(app.PositionControllerPanel);
            app.EndPositionEditFieldLabel.Text = 'End Position';
            app.EndPositionEditField = uieditfield(app.PositionControllerPanel, 'numeric');
            app.EndPositionEditField.Editable = 'off';
            app.EndPositionEditField.Value = 90;
            app.PositionControllerLimitsLabel = uilabel(app.PositionControllerPanel);
            app.PositionControllerLimitsLabel.Text = '(1-180)';
            % Show the figure after all components are created
            app.TakeMeasurement.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TakeMeasurementApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TakeMeasurement)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TakeMeasurement)
        end
    end
end